classdef App_Titulacao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        Painel_Titulante              matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        TitulanteSwitch               matlab.ui.control.Switch
        TitulanteButton               matlab.ui.control.Button
        ConcentraomolLEditFieldLabel  matlab.ui.control.Label
        Titulante_Concentrao_mol_L    matlab.ui.control.NumericEditField
        Painel_Titulado1              matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        Titulado1Switch               matlab.ui.control.Switch
        k11Label                      matlab.ui.control.Label
        K11                           matlab.ui.control.NumericEditField
        Concentrao_T11_Label          matlab.ui.control.Label
        Concentrao_T11                matlab.ui.control.NumericEditField
        k12Label                      matlab.ui.control.Label
        K12                           matlab.ui.control.NumericEditField
        Concentrao_T12_Label          matlab.ui.control.Label
        Concentrao_T12                matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        Titulado1_Label               matlab.ui.control.Button
        Concentrao_T13_Label          matlab.ui.control.Label
        Concentrao_T13                matlab.ui.control.NumericEditField
        Slider_Label                  matlab.ui.control.Label
        Painel_Volume                 matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        VolumeinicialButton_2         matlab.ui.control.Button
        Volume_mL                     matlab.ui.control.NumericEditField
        Volume_Label                  matlab.ui.control.Label
        Painel_Titulado2              matlab.ui.container.Panel
        GridLayout5_2                 matlab.ui.container.GridLayout
        Titulado2Switch               matlab.ui.control.Switch
        k21Label                      matlab.ui.control.Label
        K21                           matlab.ui.control.NumericEditField
        Concentrao_T21_Label          matlab.ui.control.Label
        Concentrao_T21                matlab.ui.control.NumericEditField
        k22Label                      matlab.ui.control.Label
        K22                           matlab.ui.control.NumericEditField
        Concentrao_T22_Label          matlab.ui.control.Label
        Concentrao_T22                matlab.ui.control.NumericEditField
        Slider_2Label                 matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        Titulado2_Label               matlab.ui.control.Button
        Concentrao_T23_Label          matlab.ui.control.Label
        Concentrao_T23                matlab.ui.control.NumericEditField
        Slider_2_Label                matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        PrincipalInterestUIAxes       matlab.ui.control.UIAxes
        TitularButton                 matlab.ui.control.Button
        Percentagem                   matlab.ui.control.NumericEditField
        CancelarButton                matlab.ui.control.StateButton
        Cancelar_Lamp                 matlab.ui.control.Lamp
        Notif_Panel                   matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        ERRO_Label                    matlab.ui.control.Label
        AVISO_Label                   matlab.ui.control.Label
        TempoRestante_Label           matlab.ui.control.Label
        Grafico_Group                 matlab.ui.container.ButtonGroup
        Grafico_pH                    matlab.ui.control.ToggleButton
        Grafico_Concentracao          matlab.ui.control.ToggleButton
        Grafico_Reacoes               matlab.ui.control.ToggleButton
        Grafico_ErroAbsoluto          matlab.ui.control.ToggleButton
        Grafico_Label                 matlab.ui.control.Label
        Minuto                        matlab.ui.control.EditField
        Percentagem_Label             matlab.ui.control.Label
        Minuto_Label                  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        File_Handler                  matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        GuardarButton                 matlab.ui.control.Button
        Save_Field                    matlab.ui.control.EditField
        CarregarButton                matlab.ui.control.Button
        Load_Field                    matlab.ui.control.EditField
        EliminarButton                matlab.ui.control.Button
        Delete_Field                  matlab.ui.control.EditField
        FicheirosdisponveisPanel      matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        Data_Files_Text               matlab.ui.control.TextArea
        RefreshButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        KH2O = 10^(-14);
        orange = [1.00,0.41,0.16];
        % Menu Principal
        Inicio          = 0;
        Calculo         = 1;
        Espera          = 2;
        Tempo_Inicial   = 1;
        title;
        Data            = [];
        Current_File;
    end
    
    % Funcoes de Estado de Menu
    % Principal
    methods (Access = private)
        function D = Estado_Menu(app, Main)
            
            switch Main
                case app.Inicio
                    % Painel da Direita
                    Estado_Painel_Fich(app, 1);
                    app.GuardarButton.Enable  = 'off';
                    RefreshButtonPushed(app, 0);
                    % Painel Central
                    Estado_Painel_Central(app, 0);
                    % Painel da Esquerda
                    Estado_Painel_Dados(app, 1);
                case app.Calculo
                    % Painel da Direita
                    Estado_Painel_Fich(app, 0);
                    % Painel Central
                    Estado_Painel_Central(app, 1);
                    % Painel da Esquerda
                    Estado_Painel_Dados(app, 0);
                case app.Espera
                    % Painel da Direita
                    Estado_Painel_Fich(app, 1);
                    % Painel Central
                    Estado_Painel_Central(app, 2);
                    % Painel da Esquerda
                    Estado_Painel_Dados(app, 1);
            end
            drawnow;
            D = 1;
        end

    % Painel de Ficheiros
        function D = Estado_Painel_Fich(app, Main)
            switch Main
                case 0
                    Value               = 'off';
                case 1
                    Value               = 'on';
            end
            app.GuardarButton.Enable    = Value;
            app.Save_Field.Enable       = Value;
            app.CarregarButton.Enable   = Value;
            app.Load_Field.Enable       = Value;
            app.EliminarButton.Enable   = Value;
            app.Delete_Field.Enable     = Value;
            app.RefreshButton.Enable    = Value;
            D = 1;
        end
    % Painel Central
    function D = Estado_Painel_Central(app, Main)
            switch Main
                case 0
                    Aparece_Notif(app, 1, 1);
                    app.Cancelar_Lamp.Color     = 'white';
                    Value                       = ["on"; "on"; "off"; "off"];
                case 1
                    Aparece_Notif(app, 1, 1);
                    app.CancelarButton.Value    = 0;
                    app.Cancelar_Lamp.Color     = 'green';
                    app.Minuto.Value            = '-';
                    Value                       = ["off"; "off"; "on"; "on"];
                case 2
                    Value                       = ["on"; "on"; "off"; "off"];
            end
            app.Grafico_Label.Enable            = Value(1);
            for c=1:size(app.Grafico_Group.Buttons,2)
                app.Grafico_Group.Buttons(c).Enable     = Value(1);
            end
            
            app.TitularButton.Enable                    = Value(2);
            
            app.TempoRestante_Label.Enable              = Value(3);
            app.Percentagem.Enable                      = Value(3);
            app.Percentagem_Label.Enable                = Value(3);
            app.Minuto.Enable                           = Value(3);
            app.Minuto_Label.Enable                     = Value(3);
            
            app.CancelarButton.Enable                   = Value(4);
            
            D = 1;
        end      
    % Painel de Dados
        function D = Estado_Painel_Dados(app, Main)
            switch Main
                case 0
                    Value = 'off';
                case 1
                    Value = 'on';
            end
            % Volume
            app.VolumeinicialButton_2.Enable            = Value;
            app.Volume_Label.Enable                     = Value;
            app.Volume_mL.Enable                        = Value;
            
            % Titulante
            app.TitulanteButton.Enable                  = Value;
            app.TitulanteSwitch.Enable                  = Value;
            app.ConcentraomolLEditFieldLabel.Enable     = Value;
            app.Titulante_Concentrao_mol_L.Enable       = Value;
            
            % Titulado 1
            app.Titulado1_Label.Enable                  = Value;
            app.Titulado1Switch.Enable                  = Value;
            app.k11Label.Enable                         = Value;
            app.K11.Enable                              = Value;
            app.Concentrao_T11_Label.Enable             = Value;
            app.Concentrao_T11.Enable                   = Value;
            app.Concentrao_T12_Label.Enable             = Value;
            app.Concentrao_T12.Enable                   = Value;
            % Titulado 1 Di
            app.k12Label.Enable                         = Value;
            app.K12.Enable                              = Value;
            app.Concentrao_T13_Label.Enable             = Value;
            app.Concentrao_T13.Enable                   = Value;
            % Seletor
            app.Slider.Enable                           = Value;
            app.Slider_Label.Enable                     = Value;
            
            % Titulado 2
            app.Titulado2_Label.Enable                  = Value;
            app.Titulado2Switch.Enable                  = Value;
            app.k21Label.Enable                         = Value;
            app.K21.Enable                              = Value;
            app.Concentrao_T21_Label.Enable             = Value;
            app.Concentrao_T21.Enable                   = Value;
            app.Concentrao_T22_Label.Enable             = Value;
            app.Concentrao_T22.Enable                   = Value;
            % Titulado 1 Di
            app.k22Label.Enable                         = Value;
            app.K22.Enable                              = Value;
            app.Concentrao_T23_Label.Enable             = Value;
            app.Concentrao_T23.Enable                   = Value;
            % Seletor
            app.Slider_2.Enable                         = Value;
            app.Slider_2_Label.Enable                   = Value;
            
            SliderValueChanged(app, 0);
            Slider_2ValueChanged(app, 0);
            
            D = 1;
        end
        %Notificacoes
        function results = Aparece_Notif(app, ERRO, AVISO)

            if  (ERRO*AVISO == 1)
                app.Notif_Panel.Visible     = 'off';
            else
                app.Notif_Panel.Visible     = 'on';
            end
            Aparece_ERRO(app, ERRO);
            Aparece_AVISO(app, AVISO);
            drawnow;
            
            results = 1;
        end        
        %Erro
        function results = Aparece_ERRO(app, state)
            Texto = [];
            if  (state == 1)
                app.ERRO_Label.Visible     = 'off';
            else
                app.Notif_Panel.Visible    = 'on';
                app.ERRO_Label.Visible     = 'on';
                if ~mod(state,2)
                    Texto = "ERRO - Ficheiro deve acabar em .mat";
                end
                if ~mod(state,3)
                    if size(Texto, 2)
                        Texto = [ Texto; ...
                                "ERRO - Ficheiro não existente"];
                    else
                        Texto = "ERRO - Ficheiro não existente";
                    end
                end
                if size(Texto,1)
                    Celula_Texto = cellstr(Texto);
                else
                    Celula_Texto = cellstr("");                 
                end
                app.ERRO_Label.Text = Celula_Texto'; 
            end
            drawnow;
            
            results = 1;
        end
        % Aviso
        function results = Aparece_AVISO(app, state)
            Texto = [];
            if (state == 1)
                app.AVISO_Label.Visible     = 'off';
            else
                app.AVISO_Label.Visible     = 'on';
                if ~mod(state,2)
                    Texto = "AVISO - Ficheiro deve acabar em .mat";
                end
                if ~mod(state,3)
                    if size(Texto, 2)
                        Texto = [ Texto; ...
                                "AVISO - Ficheiro não existente"];
                    else
                        Texto = "AVISO - Ficheiro não existente";
                    end
                end
                if size(Texto_Aviso,1)
                    Celula_Texto = cellstr(Texto);
                else
                    Celula_Texto = cellstr("");
                end
                app.AVISO_Label.Text = Celula_Texto';
            end
            drawnow;
            
            results = 1;
        end
    end
    methods (Access = public)
        function results = Tempo_Restante(app, Percentagem)
            
            tempo_passado   = (datetime('now')-app.Tempo_Inicial);
            tempo_total     = tempo_passado/Percentagem;
            results         = tempo_total-tempo_passado;
            results = datestr(results, 'MM:SS.FFF');
            app.Minuto.Value = results;
        end
    end
    
    %   Verificacao dos ficheiros .mat exitstentes
    methods (Access = private)
        function D = Ficheiros_Existentes(~)
            S = what;
            S = S.mat;
            Text = sprintf(" ");
            for c = 1:size(S,1)
                if c == 1
                    Text = sprintf("%s",S{c});
                else
                    Text = [Text; sprintf("%s",S{c})];
                end
            end
            D = Text;
        end
    end
   
    %   Compilação do Ácido Fraco Diprótico 1
    
    %   Função do Volume
    methods (Access = public)
    %   Função do Volume 
        function D = Volume_Inicial(app)
            D = app.Volume_mL.Value*10^(-3);
        end
    %   Função da Concentração do Titulante
        function D = Concentracao_Titulante(app)
            D = app.Titulante_Concentrao_mol_L.Value;
        end
    %   Função da Quantidade dos Titulados
        function D = Quantidade_Titulado_1(app)
            D = app.Concentrao_T11.Value*Volume_Inicial(app);
        end
    %   Função para Encontrar Pontos de Equilibrio Simples do Titulado
    end
    methods (Access = public)
        function D = Ponto_Equilibrio_Simples(app)
                
            D = zeros(app.Slider.Value);
            
            for c=1:app.Slider.Value
                D(c) = Quantidade_Titulado_1(app)/Concentracao_Titulante(app)*c;
            end
        end
    end
    %   Função para Cancelar os Loops
    methods (Access = public)
        
        function D = Cancelar(app)
            
            if app.CancelarButton.Value
                D = 0;
            else
                D = 1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Estado_Menu(app, app.Inicio);
        end

        % Button pushed function: TitularButton
        function TitularButtonPushed(app, event)
            %   Disables Plotting while making the function
            Estado_Menu(app, app.Calculo);
    
            QuantidadeInicial = Quantidade_Titulado_1(app);
            
            app.Tempo_Inicial = datetime('now');
            
            if app.Slider_2.Value
                switch app.Slider.Value
                    case 1
                        QuantidadeInicial = [ app.Concentrao_T21.Value*Volume_Inicial(app);...
                                              app.Concentrao_T11.Value*Volume_Inicial(app);];
                        final = COMP_Acido2Mono_1Mono_APP(app, QuantidadeInicial);
                    case 2
                        final = COMP_AcidoDiprotico_APP(app, QuantidadeInicial);
                end
            else
                switch app.Slider.Value
                    case 1
                        final = COMP_AcidoFraco_APP(app, QuantidadeInicial);
                    case 2
                        final = COMP_AcidoDiprotico_APP(app, QuantidadeInicial);
                end             
            end
            app.Data = final;
            save("autosave.mat", 'final');
            app.title = 'autosave.mat';
            
            % Makes the plot
            Grafico_Geral(app, app.PrincipalInterestUIAxes, app.Data);

            Estado_Menu(app, app.Espera);
                
        end

        % Value changed function: Titulado1Switch
        function Titulado1SwitchValueChanged(app, event)
            %   Troca o valor do outro para ser congruente
            if strcmp(app.TitulanteSwitch.Value, app.Titulado1Switch.Value)
                if strcmp(app.TitulanteSwitch.Value, app.TitulanteSwitch.ItemsData(1))
                    C = app.TitulanteSwitch.ItemsData;
                    B = C{2};
                    app.TitulanteSwitch.Value = B;
                    drawnow;
                else
                    C = app.TitulanteSwitch.ItemsData;
                    B = C{1};
                    app.TitulanteSwitch.Value = B;
                    drawnow;
                end
            end
        end

        % Value changed function: TitulanteSwitch
        function TitulanteSwitchValueChanged(app, event)
            %   Troca o valor do outro para ser congruente
            if strcmp(app.TitulanteSwitch.Value, app.Titulado1Switch.Value)
                if strcmp(app.Titulado1Switch.Value, app.Titulado1Switch.ItemsData(1))
                    C = app.Titulado1Switch.ItemsData;
                    B = C{2};
                    app.Titulado1Switch.Value = B;
                    drawnow;
                else
                    C = app.Titulado1Switch.ItemsData;
                    B = C{1};
                    app.Titulado1Switch.Value = B;
                    drawnow;
                end
            end
            app.Grafico_Group.SelectedObject
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {718, 718, 718};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {718, 718};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {423, '1x', 145};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: CancelarButton
        function CancelarButtonValueChanged(app, event)
            app.Cancelar_Lamp.Color = 'red';
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            ERRO = 1;
            
            if endsWith(app.Save_Field.Value, ".mat")
                final = app.Data;
                if isfile(app.Save_Field.Value)
                    delete(sprintf('%s',app.Save_Field.Value));
                    save(app.Save_Field.Value, 'final');
                else
                    save(app.Save_Field.Value, 'final');
                end
            else
                ERRO = ERRO*3;
            end
            
            Aparece_Notif(app, ERRO, 1);
            RefreshButtonPushed(app,0);
        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            ERRO = 1;
            
            if isfile(app.Load_Field.Value)&&endsWith(app.Load_Field.Value, ".mat")
                app.Data            = importdata(app.Load_Field.Value);
                app.Current_File    = app.Load_Field.Value;
                app.title           = app.Load_Field.Value;
                Grafico_Geral(app,app.PrincipalInterestUIAxes,app.Data);
                Estado_Menu(app,app.Espera);
            else
                if ~isfile(app.Load_Field.Value)
                    ERRO = ERRO*3;
                end
                if ~endsWith(app.Load_Field.Value, ".mat")
                    ERRO = ERRO*2;
                end
            end
            
            Aparece_Notif(app, ERRO, 1);
            RefreshButtonPushed(app,0);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            Text = Ficheiros_Existentes(app);
            app.Data_Files_Text.Value = Text;
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            ERRO = 1;            
            
            if isfile(app.Delete_Field.Value)&&endsWith(app.Delete_Field.Value, ".mat")
                delete(sprintf('%s',app.Delete_Field.Value));
                app.Error_Field.Visible   = 'off';
            else
                if ~isfile(app.Delete_Field.Value)
                    ERRO = ERRO*3;
                end
                if ~endsWith(app.Delete_Field.Value, ".mat")
                    ERRO = ERRO*2;
                end
            end
            
            Aparece_Notif(app, ERRO, 1);
            RefreshButtonPushed(app,0);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % Discretiza o Slider
            c = 2;
            Minor_1 = app.Slider.MinorTicks(1);
            Minor_2 = app.Slider.MinorTicks(1)-1;
            while (Minor_1 ~= Minor_2)&&(c <= size(app.Slider.MinorTicks,2))
                Minor_2 = Minor_1;
                if abs(app.Slider.MinorTicks(c) - app.Slider.Value) <...
                   abs(Minor_1                  - app.Slider.Value)
                   Minor_1 = app.Slider.MinorTicks(c);
                end
                if (Minor_1 ~= Minor_2)
                   c = c + 1; 
                end
            end
            app.Slider.Value = app.Slider.MinorTicks(c-1);
            
            % Faz os procedimentos
            value = app.Slider.Value;
            switch value
                case 0
                    Value = ["off"; "off"];
                case 1
                    Value = ["on"; "off"];
                    app.Concentrao_T11_Label.Text = "[ RH ] (mol/L)";
                    app.Concentrao_T12_Label.Text = "[ R ] (mol/L)";
                case 2
                    Value = ["on"; "on"];   
                    app.Concentrao_T11_Label.Text = "[ RH2 ] (mol/L)";
                    app.Concentrao_T12_Label.Text = "[ RH ] (mol/L)";   
                    app.Concentrao_T13_Label.Text = "[ R ] (mol/L)"; 
            end
            % Titulado 2 Mono
            app.Titulado1Switch.Visible                 = Value(1);
            app.k11Label.Visible                        = Value(1);
            app.K11.Visible                             = Value(1);
            app.Concentrao_T11_Label.Visible            = Value(1);
            app.Concentrao_T11.Visible                  = Value(1);
            app.Concentrao_T12_Label.Visible            = Value(1);
            app.Concentrao_T12.Visible                  = Value(1);
            % Titulado 1 Di
            app.k12Label.Visible                        = Value(2);
            app.K12.Visible                             = Value(2);
            app.Concentrao_T13_Label.Visible            = Value(2);
            app.Concentrao_T13.Visible                  = Value(2);
            
            drawnow;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            % Discretiza o Slider
            c = 2;
            Minor_1 = app.Slider_2.MinorTicks(1);
            Minor_2 = app.Slider_2.MinorTicks(1)-1;
            while (Minor_1 ~= Minor_2)&&(c <= size(app.Slider_2.MinorTicks,2))
                Minor_2 = Minor_1;
                if abs(app.Slider_2.MinorTicks(c) - app.Slider_2.Value) <...
                   abs(Minor_1                    - app.Slider_2.Value)
                   Minor_1 = app.Slider_2.MinorTicks(c);
                end
                if (Minor_1 ~= Minor_2)
                   c = c + 1; 
                end
            end
            app.Slider_2.Value = app.Slider_2.MinorTicks(c-1);
            
            % Faz os procedimentos
            value = app.Slider_2.Value;
            switch value
                case 0
                    Value = ["off"; "off"];
                case 1
                    Value = ["on"; "off"];
                    app.Concentrao_T21_Label.Text = "[ RH ] (mol/L)";
                    app.Concentrao_T22_Label.Text = "[ R ] (mol/L)";
                case 2
                    Value = ["on"; "on"];   
                    app.Concentrao_T21_Label.Text = "[ RH2 ] (mol/L)";
                    app.Concentrao_T22_Label.Text = "[ RH ] (mol/L)";   
                    app.Concentrao_T23_Label.Text = "[ R ] (mol/L)"; 
            end
            % Titulado 2 Mono
            app.Titulado2Switch.Visible                 = Value(1);
            app.k21Label.Visible                        = Value(1);
            app.K21.Visible                             = Value(1);
            app.Concentrao_T21_Label.Visible            = Value(1);
            app.Concentrao_T21.Visible                  = Value(1);
            app.Concentrao_T22_Label.Visible            = Value(1);
            app.Concentrao_T22.Visible                  = Value(1);
            % Titulado 1 Di
            app.k22Label.Visible                        = Value(2);
            app.K22.Visible                             = Value(2);
            app.Concentrao_T23_Label.Visible            = Value(2);
            app.Concentrao_T23.Visible                  = Value(2);
 
            drawnow;
        end

        % Selection changed function: Grafico_Group
        function Grafico_GroupSelectionChanged(app, event)
            if size(app.Data,1) ~= 0
                Grafico_Geral(app, app.PrincipalInterestUIAxes, app.Data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.Painel_Titulante = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Painel_Titulante);
            app.TitulanteSwitch = uiswitch(app.GridLayout4, 'slider');
            app.TitulanteSwitch.Items = {'Ácido', 'Base'};
            app.TitulanteSwitch.ItemsData = {'Ácido', 'Base'};
            app.TitulanteSwitch.ValueChangedFcn = createCallbackFcn(app, @TitulanteSwitchValueChanged, true);
            app.TitulanteSwitch.Value = 'Base';
            app.TitulanteButton = uibutton(app.GridLayout4, 'push');
            app.TitulanteButton.Text = 'Titulante';
            app.ConcentraomolLEditFieldLabel = uilabel(app.GridLayout4);
            app.ConcentraomolLEditFieldLabel.Text = {'Concentração (mol/L)'; ''};
            app.Titulante_Concentrao_mol_L = uieditfield(app.GridLayout4, 'numeric');
            app.Titulante_Concentrao_mol_L.Limits = [0 40];
            app.Titulante_Concentrao_mol_L.Value = 1;
            app.Painel_Titulado1 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Painel_Titulado1);
            app.Titulado1Switch = uiswitch(app.GridLayout5, 'slider');
            app.Titulado1Switch.Items = {'Ácido', 'Base'};
            app.Titulado1Switch.ItemsData = {'Ácido', 'Base'};
            app.Titulado1Switch.ValueChangedFcn = createCallbackFcn(app, @Titulado1SwitchValueChanged, true);
            app.Titulado1Switch.Value = 'Ácido';
            app.k11Label = uilabel(app.GridLayout5);
            app.k11Label.Text = 'k1';
            app.K11 = uieditfield(app.GridLayout5, 'numeric');
            app.K11.Limits = [0 Inf];
            app.K11.ValueDisplayFormat = '%11.3e';
            app.K11.Value = 0.001;
            app.Concentrao_T11_Label = uilabel(app.GridLayout5);
            app.Concentrao_T11_Label.Text = {'[ RH2 ] (mol/L)'; ''};
            app.Concentrao_T11 = uieditfield(app.GridLayout5, 'numeric');
            app.Concentrao_T11.Limits = [0 40];
            app.Concentrao_T11.Value = 0.01;
            app.k12Label = uilabel(app.GridLayout5);
            app.k12Label.Text = 'k2';
            app.K12 = uieditfield(app.GridLayout5, 'numeric');
            app.K12.Limits = [0 Inf];
            app.K12.ValueDisplayFormat = '%11.3e';
            app.K12.Value = 1e-09;
            app.Concentrao_T12_Label = uilabel(app.GridLayout5);
            app.Concentrao_T12_Label.Text = '[ RH ] (mol/L)';
            app.Concentrao_T12 = uieditfield(app.GridLayout5, 'numeric');
            app.Concentrao_T12.Limits = [0 40];
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = '';
            app.Slider = uislider(app.GridLayout5);
            app.Slider.Limits = [1 2];
            app.Slider.MajorTicks = [1 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 2];
            app.Slider.Value = 1;
            app.Titulado1_Label = uibutton(app.GridLayout5, 'push');
            app.Titulado1_Label.Text = {'Titulado'; '1'};
            app.Concentrao_T13_Label = uilabel(app.GridLayout5);
            app.Concentrao_T13_Label.Text = '[ R ] (mol/L)';
            app.Concentrao_T13 = uieditfield(app.GridLayout5, 'numeric');
            app.Concentrao_T13.Limits = [0 40];
            app.Slider_Label = uilabel(app.GridLayout5);
            app.Slider_Label.Text = 'Nº de reações';
            app.Painel_Volume = uipanel(app.GridLayout3);
            app.GridLayout10 = uigridlayout(app.Painel_Volume);
            app.VolumeinicialButton_2 = uibutton(app.GridLayout10, 'push');
            app.VolumeinicialButton_2.Text = {'Volume '; 'inicial'};
            app.Volume_mL = uieditfield(app.GridLayout10, 'numeric');
            app.Volume_mL.Limits = [0 100];
            app.Volume_mL.Value = 1;
            app.Volume_Label = uilabel(app.GridLayout10);
            app.Volume_Label.Text = 'Volume (mL)';
            app.Painel_Titulado2 = uipanel(app.GridLayout3);
            app.GridLayout5_2 = uigridlayout(app.Painel_Titulado2);
            app.Titulado2Switch = uiswitch(app.GridLayout5_2, 'slider');
            app.Titulado2Switch.Items = {'Ácido', 'Base'};
            app.Titulado2Switch.ItemsData = {'Ácido', 'Base'};
            app.Titulado2Switch.Value = 'Ácido';
            app.k21Label = uilabel(app.GridLayout5_2);
            app.k21Label.Text = 'k1';
            app.K21 = uieditfield(app.GridLayout5_2, 'numeric');
            app.K21.Limits = [0 Inf];
            app.K21.ValueDisplayFormat = '%11.3e';
            app.K21.Value = 0.001;
            app.Concentrao_T21_Label = uilabel(app.GridLayout5_2);
            app.Concentrao_T21_Label.Text = {'[ RH2 ] (mol/L)'; ''};
            app.Concentrao_T21 = uieditfield(app.GridLayout5_2, 'numeric');
            app.Concentrao_T21.Limits = [0 40];
            app.Concentrao_T21.Value = 0.01;
            app.k22Label = uilabel(app.GridLayout5_2);
            app.k22Label.Text = 'k2';
            app.K22 = uieditfield(app.GridLayout5_2, 'numeric');
            app.K22.Limits = [0 Inf];
            app.K22.ValueDisplayFormat = '%11.3e';
            app.K22.Value = 1e-09;
            app.Concentrao_T22_Label = uilabel(app.GridLayout5_2);
            app.Concentrao_T22_Label.Text = '[ RH ] (mol/L)';
            app.Concentrao_T22 = uieditfield(app.GridLayout5_2, 'numeric');
            app.Concentrao_T22.Limits = [0 40];
            app.Slider_2Label = uilabel(app.GridLayout5_2);
            app.Slider_2Label.Text = '';
            app.Slider_2 = uislider(app.GridLayout5_2);
            app.Slider_2.Limits = [0 2];
            app.Slider_2.MajorTicks = [0 1 2];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [0 1 2];
            app.Titulado2_Label = uibutton(app.GridLayout5_2, 'push');
            app.Titulado2_Label.Text = {'Titulado'; '2'};
            app.Concentrao_T23_Label = uilabel(app.GridLayout5_2);
            app.Concentrao_T23_Label.Text = '[ R ] (mol/L)';
            app.Concentrao_T23 = uieditfield(app.GridLayout5_2, 'numeric');
            app.Concentrao_T23.Limits = [0 40];
            app.Slider_2_Label = uilabel(app.GridLayout5_2);
            app.Slider_2_Label.Text = 'Nº de reações';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.PrincipalInterestUIAxes = uiaxes(app.GridLayout2);
            title(app.PrincipalInterestUIAxes, '')
            xlabel(app.PrincipalInterestUIAxes, '')
            ylabel(app.PrincipalInterestUIAxes, '')
            app.TitularButton = uibutton(app.GridLayout2, 'push');
            app.TitularButton.ButtonPushedFcn = createCallbackFcn(app, @TitularButtonPushed, true);
            app.TitularButton.Text = 'Titular';
            app.Percentagem = uieditfield(app.GridLayout2, 'numeric');
            app.Percentagem.Editable = 'off';
            app.CancelarButton = uibutton(app.GridLayout2, 'state');
            app.CancelarButton.ValueChangedFcn = createCallbackFcn(app, @CancelarButtonValueChanged, true);
            app.CancelarButton.Enable = 'off';
            app.CancelarButton.Text = 'Cancelar';
            app.Cancelar_Lamp = uilamp(app.GridLayout2);
            app.Notif_Panel = uipanel(app.GridLayout2);
            app.GridLayout11 = uigridlayout(app.Notif_Panel);
            app.ERRO_Label = uilabel(app.GridLayout11);
            app.ERRO_Label.Text = 'Erro';
            app.AVISO_Label = uilabel(app.GridLayout11);
            app.AVISO_Label.Text = 'Aviso';
            app.TempoRestante_Label = uilabel(app.GridLayout2);
            app.TempoRestante_Label.Text = 'Tempo Restante';
            app.Grafico_Group = uibuttongroup(app.GridLayout2);
            app.Grafico_Group.SelectionChangedFcn = createCallbackFcn(app, @Grafico_GroupSelectionChanged, true);
            app.Grafico_pH = uitogglebutton(app.Grafico_Group);
            app.Grafico_pH.Text = 'pH';
            app.Grafico_pH.Value = true;
            app.Grafico_Concentracao = uitogglebutton(app.Grafico_Group);
            app.Grafico_Concentracao.Text = 'Concentração';
            app.Grafico_Reacoes = uitogglebutton(app.Grafico_Group);
            app.Grafico_Reacoes.Text = 'Reações';
            app.Grafico_ErroAbsoluto = uitogglebutton(app.Grafico_Group);
            app.Grafico_ErroAbsoluto.Text = 'Erro Absoluto';
            app.Grafico_Label = uilabel(app.GridLayout2);
            app.Grafico_Label.Text = 'Gráfico';
            app.Minuto = uieditfield(app.GridLayout2, 'text');
            app.Minuto.Editable = 'off';
            app.Minuto.Tooltip = {'mm:ss:fff'};
            app.Percentagem_Label = uilabel(app.GridLayout2);
            app.Percentagem_Label.Text = '%';
            app.Minuto_Label = uilabel(app.GridLayout2);
            app.Minuto_Label.Text = 'm';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.RightPanel);
            app.File_Handler = uipanel(app.GridLayout8);
            app.File_Handler.Title = 'Painel de ficheiros';
            app.GridLayout7 = uigridlayout(app.File_Handler);
            app.GuardarButton = uibutton(app.GridLayout7, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Save_Field = uieditfield(app.GridLayout7, 'text');
            app.Save_Field.Value = 'data_basic.mat';
            app.CarregarButton = uibutton(app.GridLayout7, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.Load_Field = uieditfield(app.GridLayout7, 'text');
            app.Load_Field.Value = 'data_basic.mat';
            app.EliminarButton = uibutton(app.GridLayout7, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.Delete_Field = uieditfield(app.GridLayout7, 'text');
            app.FicheirosdisponveisPanel = uipanel(app.GridLayout8);
            app.FicheirosdisponveisPanel.Title = 'Ficheiros disponíveis';
            app.GridLayout9 = uigridlayout(app.FicheirosdisponveisPanel);
            app.Data_Files_Text = uitextarea(app.GridLayout9);
            app.Data_Files_Text.Editable = 'off';
            app.RefreshButton = uibutton(app.GridLayout9, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Titulacao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end